classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_0     matlab.ui.control.RadioButton
        Button_1     matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Button_4     matlab.ui.control.RadioButton
        Button_6     matlab.ui.control.RadioButton
        Label_title  matlab.ui.control.Label
        Label_sta    matlab.ui.control.Label
        Button_7     matlab.ui.control.Button
        Button_8     matlab.ui.control.StateButton
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Callback function: UIFigure
        function UIFigureWindowButtonDown(app, event)
        %检测是否有按键按下
            global ButtonDown loc0 modetype penloc t_penloc All_enable;
            
            if modetype==5&All_enable==1
                t_penloc=t_penloc+1;
                ButtonDown=1; 
                loc0=get(app.UIAxes, 'CurrentPoint');
                
                if t_penloc==5
                    penloc=[penloc;loc0];
                    t_penloc=0;
                end
            end
        end

        % Callback function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %检测按下鼠标后的移动
            global ButtonDown loc0 modetype penloc All_enable la lb y1 x1 s3 s7;
            if ButtonDown==1&modetype==5&All_enable==1
                loc1=get(app.UIAxes, 'CurrentPoint');
                line(app.UIAxes,[loc0(1,1) loc1(1,1)],[loc0(1,2) loc1(1,2)],'color',[0,0,0]);
                penloc=[penloc;loc1];
                loc0=loc1;
                x1=loc1(1,1);y1=loc1(1,2);
                l=sqrt(x1^2+y1^2);
                d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                writePosition(s7,d7);
                writePosition(s3,d3);
            end
        end

        % Callback function: UIFigure
        function UIFigureWindowButtonUp(app, event)
        %检测鼠标按键是否松开
            global ButtonDown;
            ButtonDown=0;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
        %功能选择
        %检测按钮组的按键哪个按下
            global modetype All_enable a  s2 s3 s4 s5 s6 s7 la lb x0 y0;
            if All_enable==1
                selectedButton = app.ButtonGroup.SelectedObject;
                buttonc=get(selectedButton,'Text');
                switch buttonc
                    case '复位'
                        modetype=0;
                        %舵机的初始状态
                        writePosition(s6,0.9);
                        pause(1);
                        writePosition(s2,0.88);
                        writePosition(s3,0.8);
                        writePosition(s4,0.5);
                        writePosition(s5,0.6); 
                        writePosition(s7,0.7);
                        app.Label_sta.Text = '状态：初始';
                        %line(app.UIAxes,[1 2],[1 2]);
                    case '画三角形'
                        modetype=1;
                        %第一条边
                        app.Label_sta.Text = '状态：“1”字';
                        x=8;y=2;
                        x1=x0+x;y1=y0-y;
                        writePosition(s2,0.93);
                        writePosition(s6,0.9);
                        pause(3)
                        l=sqrt(x1^2+y1^2);
                        d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                        d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                        writePosition(s3,d3);
                        writePosition(s7,d7);
                        pause(2)
                        writePosition(s6,0.8);
                        writePosition(s2,0.88);
                        pause(2)
                        
                        x=8;y=12;
                        for i=1:10
                            y=2+10*(i/10);
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos(abs((la^2+lb^2-l^2)/(2*la*lb)))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            pause(0.1)
                        end
                        pause(2);
                        %第二条边
                        x=2;y=6;
                        for i=1:10
                            x=8-6*(i/10);y=12-6*(i/10);
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s7,d7);
                            writePosition(s3,d3);
                            pause(0.1)
                        end
                        pause(2)
                        %第三条边
                        x=8;y=2;
                        for i=1:10
                            x=2+6*(i/10);y=6-4*(i/10);
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            pause(0.1)
                        end
                        pause(2)


%                         for i=1:100
%                             if i==1
%                                 writePosition(s6,0.9);
%                                 pause(1)
%                                 y=2+10*(i/200);
%                                 x1=x0+x;
%                                 y1=y0-y;
%                                 l=sqrt(x1^2+y1^2);
%                                 d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
%                                 d3= acos(abs((la^2+lb^2-l^2)/(2*la*lb)))/pi;
%                                 writePosition(s3,d3);
%                                 writePosition(s7,d7);
%                                 pause(1)
%                                 writePosition(s6,0.8);
%                             else
%                                 y=2+10*(i/100);
%                                 x1=x0+x;
%                                 y1=y0-y;
%                                 l=sqrt(x1^2+y1^2);
%                                 d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi;
%                                 d3= acos(abs((la^2+lb^2-l^2)/(2*la*lb)))/pi+0.03;
%                                 writePosition(s3,d3);
%                                 writePosition(s6,0.8);
%                                 writePosition(s7,d7);
%                                 pause(0.1)
%                             end
%                         end
                    case '写A'
                        modetype=2;
                        app.Label_sta.Text = '状态：“a”字母';
                        %第一斜线
                        %x=8;y=0;x2=5;y2=12;
                        writePosition(s2,0.95);
                        writePosition(s6,0.85);
                        pause(2)
                        x=5;y=0;
                        x1=x0+x;y1=y0-y;
                        l=sqrt(x1^2+y1^2);
                        d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                        d3=acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                        writePosition(s3,d3);
                        writePosition(s7,d7);
                        pause(2)
                        writePosition(s2,0.88);
                        writePosition(s6,0.8);
                        pause(2)
                        
                        for i=1:10
                            x=5-3*(i/10);y=7*(i/10);
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            pause(0.1)
                        end
                        writePosition(s2,0.95);
                        writePosition(s6,0.9);
                        pause(2)
                        %第二斜线
                        %x=8;y=0;x2=11;y2=12;
                        writePosition(s2,0.95);
                        writePosition(s6,0.9);
                        pause(2)
                        x=5;y=0;
                        x1=x0+x;y1=y0-y;
                        l=sqrt(x1^2+y1^2);
                        d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                        d3=acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                        writePosition(s3,d3);
                        writePosition(s7,d7);
                        pause(2)
                        writePosition(s2,0.88);
                        writePosition(s6,0.8);
                        pause(2)
                        
                        for i=1:10
                            x=5+3*(i/10);y=7*(i/10);
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            pause(0.1)
                        end
                        writePosition(s2,0.95);
                        writePosition(s6,0.9);
                        pause(2)
                        
                        %第三斜线
                        %x3=6.5;y3=6;x2=9.5;y2=6;749
                        writePosition(s2,0.95);
                        writePosition(s6,0.9);
                        pause(2)
                        x=2;y=4;
                        x1=x0+x;y1=y0-y;
                        l=sqrt(x1^2+y1^2);
                        d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                        d3=acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                        writePosition(s3,d3);
                        writePosition(s7,d7);
                        pause(2)
                        writePosition(s2,0.88);
                        writePosition(s6,0.8);
                        pause(2)
                        
                        for i=1:10
                            x=3.5+3*(i/10);y=4;
                            x1=x0+x;y1=y0-y+i/10;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            pause(0.1)
                        end
                        writePosition(s2,0.95);
                        writePosition(s6,0.9);
                        pause(2)
                        
                    case '写“不忘初心、牢记使命”'
                        modetype=3;
                        app.Label_sta.Text = '状态：不忘初心、牢记使命！';
                        
                    case '寻找黑框'
                        modetype=4;
                        midx=0;midy=0;nx=0;ny=0;
                        writePosition(s6,0.85);
                        writePosition(s2,0.95);
                        pause(2)
                        app.Label_sta.Text = '状态：马上会找到框的';
                        for ix=1:40
                            y=8;
                            x=16*ix/40;
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos((la^2+l^2-lb^2)/(2*la*l) ) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            m=readDigitalPin(a,'A5');
                            if m==1
                                midx=midx+x1; 
                                nx=nx+1;
                            end
                            pause(0.1)                            
                        end
                        
                        for iy=1:40
                            x=8;
                            y=16*iy/40;
                            x1=x0+x;y1=y0-y;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos((la^2+l^2-lb^2)/(2*la*l) ) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            writePosition(s3,d3);
                            writePosition(s7,d7);
                            m=readDigitalPin(a,'A5');
                            if m==1
                                midy=midy+y1;
                                
                                ny=ny+1;
                            end
                            pause(0.1)                            
                        end
                        
                        
                        x1=midx/nx;y1=midy/ny;
                        l=sqrt(x1^2+y1^2);
                        d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                        d3=acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                        writePosition(s3,d3);
                        writePosition(s7,d7);
                        pause(2)
                        writePosition(s2,0.88);
                        writePosition(s6,0.8);
                        %x=x1-x0+3;y=y1-y0+3;
                        
                        for i=1:10
                            x1=x1+3*i/10;y1=y1-3*i/10;
                            l=sqrt(x1^2+y1^2);
                            d7=1-( atan(abs(y1/x1))+acos(abs( (la^2+l^2-lb^2)/(2*la*l) )) )/pi+0.03;
                            d3= acos((la^2+lb^2-l^2)/(2*la*lb))/pi;
                            if d3>0&d3<1&d7>0&d7<1
                                writePosition(s3,d3);
                                writePosition(s7,d7);
                                
                                pause(0.1)
                            end
                        end
                        
                    case '绘图'
                        modetype=5;
                        app.Label_sta.Text = '状态：请开始你的绘画';
                end
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %换纸
            %将坐标区域清空
            cla(app.UIAxes)
            %cla reset
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
        %启动按键
        %初始化函数
        global t_penloc penloc All_enable a s2 s3 s4 s5 s6 s7 la lb x0 y0;
            value = app.Button_8.Value;
            if value==1                
                a=arduino('COM6','Uno','Libraries','Servo');%连接arduino
                %s1 = servo(a,'D2');
                s2 = servo(a,'D3');
                s3 = servo(a,'D4');
                s4 = servo(a,'D5');
                s5 = servo(a,'D6');
                s6 = servo(a,'D7');
                s7 = servo(a,'D8');
                %舵机的初始状态
                
                writePosition(s2,0.88);
                writePosition(s3,0.8);
                writePosition(s4,0.5);
                writePosition(s5,0.6);
                writePosition(s6,0.85);
                writePosition(s7,0.7);
                app.Label_sta.Text = '状态：启动成功';
                %writePosition(s1,0);
                %writePosition(s2,1);
                la=10;lb=13.5;
                x0=-16;y0=16.25;
                t_penloc=0;
                penloc=[];
                All_enable=1;
                
            else
                All_enable=0;
                app.Label_sta.Text = '状态：未启动';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-16.4 0];
            app.UIAxes.YLim = [0 12];
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '功能选择';
            app.Button_0 = uiradiobutton(app.ButtonGroup);
            app.Button_0.Text = '复位';
            app.Button_0.Value = true;
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '画三角形';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '写A';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '寻找黑框';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '绘图';
            app.Label_title = uilabel(app.UIFigure);
            app.Label_title.Text = '控制窗口';
            app.Label_sta = uilabel(app.UIFigure);
            app.Label_sta.Text = '状态：未启动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '换纸';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '启动';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end